classdef doeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DDOEUIFigure          matlab.ui.Figure
        GridLayout4           matlab.ui.container.GridLayout
        phasePanel            matlab.ui.container.Panel
        GridLayout10          matlab.ui.container.GridLayout
        phaseOperPanel        matlab.ui.container.Panel
        GridLayout9           matlab.ui.container.GridLayout
        outputFormDropDown    matlab.ui.control.DropDown
        compleCheckBox        matlab.ui.control.CheckBox
        phaseOperButton       matlab.ui.control.Button
        phaseQuantiDropDown   matlab.ui.control.DropDown
        phaseUIAxes           matlab.ui.control.UIAxes
        ampPanel              matlab.ui.container.Panel
        GridLayout7           matlab.ui.container.GridLayout
        indicatorPanel        matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        SSEEditField          matlab.ui.control.NumericEditField
        SSEEditFieldLabel     matlab.ui.control.Label
        utilRateEditField     matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        similarityEditField   matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        outputImgUIAxes       matlab.ui.control.UIAxes
        startButton           matlab.ui.control.Button
        algorithmDropDown     matlab.ui.control.DropDown
        TargetPanel           matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        CheckBox              matlab.ui.control.CheckBox
        loadTargetImgButton   matlab.ui.control.Button
        targetImgUIAxes       matlab.ui.control.UIAxes
        DOEPanel              matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        n1xn2Label            matlab.ui.control.Label
        xLabel_3              matlab.ui.control.Label
        n2EditField           matlab.ui.control.EditField
        n1EditField           matlab.ui.control.EditField
        xLabel_2              matlab.ui.control.Label
        DOEW                  matlab.ui.control.NumericEditField
        DOEH                  matlab.ui.control.NumericEditField
        xLabel                matlab.ui.control.Label
        simpleNumberW         matlab.ui.control.NumericEditField
        xmmLabel              matlab.ui.control.Label
        simpleNumberH         matlab.ui.control.NumericEditField
        simpleNumberLabel     matlab.ui.control.Label
        PanelLight            matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        spotRadiusEditField   matlab.ui.control.NumericEditField
        mmLabel_3             matlab.ui.control.Label
        waistRadiusEditField  matlab.ui.control.NumericEditField
        mmLabel               matlab.ui.control.Label
        wavelengthEditField   matlab.ui.control.NumericEditField
        nmLabel               matlab.ui.control.Label
    end


    properties (Access = private)
        TargetImg % 目标图像
        TargetImgPath % 目标图像路径
        TargetImgCmap % 颜色图，索引图才有
        TargetGrayImg % 目标灰度图
        F_image % Description
        IF_image_phase % gs迭代出来的相位
        idea_image % Description
        idea_image_bw % Description
        E % Description
        outPhaseImg % 需要导出的相位
        iterProgressdlg% Description
    end

    methods (Access = private)
        %计算加上随机相位的振幅分布
        function E = calAmpliDistribution(app)
            wr = app.waistRadiusEditField.Value;
            %             sr = app.spotRadiusEditField.Value;
            H = app.DOEH.Value;
            W = app.DOEW.Value;
            numH = app.simpleNumberH.Value;
            numW = app.simpleNumberW.Value;
            h = linspace(-H/2,H/2,numH);
            w = linspace(-W/2,W/2,numW);
            [x,y] = meshgrid(w,h);
            E = exp(-(x.^2+y.^2)/(wr^2));
        end

        function [F_image, IF_image_phase] = gs(app)
            idea_image0 = resizeImage(app);
            app.idea_image_bw = idea_image0 > 1/255;
            app.idea_image = sqrt(idea_image0);% 理论振幅图


            IF_image_phase = (2*rand(app.simpleNumberH.Value, app.simpleNumberW.Value) - 1)*pi;%初始随机相位
            estimated_image = app.E.*exp(1i*IF_image_phase); %模拟初次经过DOE后高斯光束
            % 改变了采样点数，就不行了
            %迭代次数
            numgs = 500;
            seta = 0.05;
            for m = 1:numgs
                F_image = fftshift(fft2(estimated_image));%第k次迭代时，输出光场
                absF_image = abs(F_image);
                absF_image = absF_image./max(max(absF_image));%归一化

                sim = corrcoef(app.idea_image,absF_image);%振幅的相关系数
                similarity = sim(1,2);
                if similarity>=0.99
                    break
                end

                F_image_phase = angle(F_image);%保留相位
                absF_image(app.idea_image_bw) = 0;
                F_estimated_image =(absF_image*seta + app.idea_image).*exp(1i*F_image_phase);%对输出光场振幅和理论输出光场振幅进行交换
                IF_image = ifft2(ifftshift(F_estimated_image));%反傅里叶变换后，计算的k+1次输入光场
                IF_image_phase = angle(IF_image);%保留相位
                estimated_image = app.E.*exp(1i*IF_image_phase);%实际第k+1次输入光场

                updateIterationProcess(app, m/numgs);%更新进度条
            end
        end

        function image0 = resizeImage(app)
            %目标图像整形，以使得符合采样点数%
            H = app.simpleNumberH.Value;
            W = app.simpleNumberW.Value;
            [tImgH, tImgW]= size(app.TargetGrayImg);
            rh = tImgH/app.simpleNumberH.Value;
            rw = tImgW/app.simpleNumberW.Value;
            if rh > rw
                idea_image0 = imresize(app.TargetGrayImg,[H, NaN],'bilinear');
            else
                idea_image0 = imresize(app.TargetGrayImg,[NaN, W],'bilinear');
            end

            [tImgH, tImgW]= size(idea_image0);
            image0 = zeros(H,W);
            startH = int16((H - tImgH)/2)+1;
            endH = startH+tImgH-1;
            startW = int16((W - tImgW)/2)+1;
            endW = startW+tImgW-1;
            image0(startH:endH,startW:endW) = idea_image0;
        end

        function [F_image, IF_image_phase] = algorithmIteration(app)
            app.iterProgressdlg = uiprogressdlg(app.DDOEUIFigure,'Title','Please Wait',...
                'Message','Processing the data');
            try
                [F_image, IF_image_phase] = gs(app);
            catch ME
                % Close dialog box
                closeProcessDialog(app);
                % rethrow exception
                rethrow(ME);
            end
            closeProcessDialog(app);
        end

        % 关闭进度条对话框
        function closeProcessDialog(app)
            updateIterationProcess(app, 1);
            app.iterProgressdlg.Message = 'Finish';
            pause(0.5);
            close(app.iterProgressdlg);
        end

        % 更新进度条
        function updateIterationProcess(app, value)
            app.iterProgressdlg.Value = value;
        end

        % 展示评价参数
        function refeshIndicator(app)
            app.similarityEditField.Value = calSimility(app);
            app.SSEEditField.Value = calSSE(app);
            app.utilRateEditField.Value = calUtiliRate(app);
        end

        function sim = calSimility(app)
            absF_image = abs(app.F_image);
            absF_image = absF_image./max(max(absF_image));%归一化
            sim = corrcoef(app.idea_image, absF_image);%振幅的相关系数
            sim = sim(1,2);
        end

        function sse = calSSE(app)
            absF_image = abs(app.F_image);
            absF_image = absF_image./max(max(absF_image));%归一化
            sse = sum(sum((absF_image - app.idea_image).^2))/(sum(sum(app.idea_image.^2)));
        end

        function n = calUtiliRate(app)
            absF_image = abs(app.F_image);
            absF_image = absF_image./max(max(absF_image));%归一化
            n = sum(absF_image(app.idea_image_bw).^2)/(sum(sum(absF_image.^2)));
        end

        function showAbsImg(app, absF_image)
            absF_image = absF_image./max(max(absF_image));%归一化
            imshow(absF_image, "Parent", app.outputImgUIAxes);
        end

        function showPhaseImg(app)
            phaseImg = app.outPhaseImg/(max(max(app.outPhaseImg)));
            imshow(phaseImg, "Parent", app.phaseUIAxes);
        end

        function clearParam(app)
            app.phaseQuantiDropDown.Value = "1"; % 重置为连续
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.phaseUIAxes.Visible = 'off';
%             app.outputImgUIAxes.Visible = 'off';
%             app.targetImgUIAxes.Visible = 'off';

            axis(app.targetImgUIAxes, 'image');
            axis(app.phaseUIAxes, 'image');
            axis(app.outputImgUIAxes, 'image');
            [targetImg, cmap] = imread("02.jpg");
            imshow(targetImg, cmap, "Parent", app.targetImgUIAxes);
            imshow(targetImg, cmap, "Parent", app.outputImgUIAxes);
            imshow(targetImg, cmap, "Parent", app.phaseUIAxes);
        end

        % Button pushed function: loadTargetImgButton
        function loadTargetImgButtonPushed(app, event)
            filterspec = {'*.jpg;*.png','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if ischar(f) && ischar(p)
                % 组装全路径
                app.TargetImgPath = fullfile(p,f);
                try
                    [targetImg, cmap] = imread(app.TargetImgPath);
                    app.TargetImg = targetImg;
                    app.TargetImgCmap = cmap;
                catch ME
                    % If problem reading image, display error message
                    uialert(app.DDOEUIFigure, ME.message, 'Image Error');
                    return;
                end

                if isempty(app.TargetImgCmap)
                    app.TargetGrayImg = rgb2gray(app.TargetImg);
                else
                    app.TargetGrayImg = ind2gray(app.TargetImg, app.TargetImgCmap);
                end
                app.TargetGrayImg = im2double(app.TargetGrayImg);
                app.TargetGrayImg = app.TargetGrayImg/max(max(app.TargetGrayImg));%归一化
                imshow(sqrt(app.TargetGrayImg), "Parent", app.targetImgUIAxes);

                % 显示和重置反转按钮
                app.CheckBox.Enable = true;
                app.CheckBox.Value = false;
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            %先校验必填参数填了没有
            if app.waistRadiusEditField.Value <= 0
                uialert(app.DDOEUIFigure, "请输入束腰半径", 'Input Error');
                return;
            end
            if app.DOEH.Value <= 0
                uialert(app.DDOEUIFigure, "请输入DOE的高", 'Input Error');
                return;
            end
            if app.DOEW.Value <= 0
                uialert(app.DDOEUIFigure, "请输入DOE的宽", 'Input Error');
                return;
            end
            if app.simpleNumberH.Value <= 0 || app.simpleNumberW.Value <= 0
                uialert(app.DDOEUIFigure, "请输入采样点数", 'Input Error');
                return;
            end
            if isempty(app.TargetImg)
                uialert(app.DDOEUIFigure, "请加载目标图像", 'Input Error');
                return;
            end

            % 计算光源的光强分布
            app.E = calAmpliDistribution(app);

            % gs迭代
            try
                [app.F_image, app.IF_image_phase] = algorithmIteration(app);
            catch
                uialert(app.DDOEUIFigure, "计算相位出错", 'Run Error');
                return;
            end


            % 将相位从-pi~pi变换成0~2pi
            app.IF_image_phase  = mod(app.IF_image_phase, 2*pi);
            app.outPhaseImg = app.IF_image_phase;

            % 展示输出图像和相位
            absF_image = abs(app.F_image);
            showAbsImg(app, absF_image);
            showPhaseImg(app)

            %展示评价参数
            refeshIndicator(app);

            % enable相位导出按钮
            app.phaseOperButton.Enable = true;
            clearParam(app);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if ~isempty(app.TargetGrayImg)
                app.TargetGrayImg = imcomplement(app.TargetGrayImg);
                app.TargetGrayImg = app.TargetGrayImg/max(max(app.TargetGrayImg));
                imshow(sqrt(app.TargetGrayImg), "Parent", app.targetImgUIAxes);
            end
        end

        % Button pushed function: phaseOperButton
        function phaseOperButtonPushed(app, event)
            filterType = {'*.xlsx;*.xlsm';'*.txt';'*.dat';'*.csv'};
            [file,path] = uiputfile(filterType);
            if ischar(file) && ischar(path) && ~isempty(app.IF_image_phase)
                filename = fullfile(path,file);
                outPhase = app.outPhaseImg;
                if isempty(outPhase)
                    outPhase = app.IF_image_phase;
                end

                if app.compleCheckBox.Value
                    outPhase = 2*pi - outPhase;
                end

                switch app.outputFormDropDown.Value
                    case "1"
                        outPhase = outPhase;
                    case "2"
                        outPhase = outPhase /(2*pi);
                    case "3"
                        if app.n1EditField.Value <= 0
                            uialert(app.DDOEUIFigure, "请输入折射率n1", 'Input Error');
                            return;
                        end

                        if app.n2EditField.Value <= 0
                            uialert(app.DDOEUIFigure, "请输入折射率n2", 'Input Error');
                            return;
                        end
                        if app.wavelengthEditField.Value <= 0
                            uialert(app.DDOEUIFigure, "请输入波长", 'Input Error');
                            return;
                        end
                        outPhase = outPhase /(2*pi);
                        n1 = app.n1EditField.Value;
                        n2 = app.n2EditField.Value;
                        outPhase = outPhase / (n1 - n2);
                end

                try
                    if endsWith(file, '.txt')
                        writematrix(outPhase, filename,'Encoding', 'UTF-8');
                    else
                        writematrix(outPhase, filename);
                    end
                catch
                    uialert(app.DDOEUIFigure, "输出相位到文件出现错误", 'Output Phase Error');
                    return;
                end
            end
        end

        % Value changed function: phaseQuantiDropDown
        function phaseQuantiDropDownValueChanged(app, event)
            if isempty(app.IF_image_phase)
                return;
            end

            value = app.phaseQuantiDropDown.Value;
            switch value
                case "1"
                    app.outPhaseImg = app.IF_image_phase;
                case "2"
                    app.outPhaseImg = floor(app.IF_image_phase/(2*pi/8)) * (2*pi/8);
                case "3"
                    app.outPhaseImg = floor(app.IF_image_phase/(2*pi/16)) * (2*pi/16);
            end
            if ~isempty(app.outPhaseImg)
                % 量化之后再计算一次
                esImg = app.E.*exp(1i*app.outPhaseImg);
                app.F_image = fftshift(fft2(esImg));%第k次迭代时，输出光场

                absF_image = abs(app.F_image);
                absF_image = absF_image./max(max(absF_image));%归一化

                refeshIndicator(app);

                showAbsImg(app, absF_image);
                showPhaseImg(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DDOEUIFigure = uifigure('Visible', 'off');
            app.DDOEUIFigure.Name = 'DDOE';
            app.DDOEUIFigure.Icon = 'icon.png';
            app.GridLayout4 = uigridlayout(app.DDOEUIFigure);
            app.PanelLight = uipanel(app.GridLayout4);
            app.PanelLight.Title = '光源信息';
            app.GridLayout2 = uigridlayout(app.PanelLight);
            app.nmLabel = uilabel(app.GridLayout2);
            app.nmLabel.Text = '波长(nm): ';
            app.wavelengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.Value = 514;
            app.mmLabel = uilabel(app.GridLayout2);
            app.mmLabel.Text = '束腰半径(mm): ';
            app.waistRadiusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.waistRadiusEditField.Limits = [0 Inf];
            app.waistRadiusEditField.Value = 3;
            app.mmLabel_3 = uilabel(app.GridLayout2);
            app.mmLabel_3.Text = '光斑半径(mm): ';
            app.spotRadiusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.spotRadiusEditField.Limits = [0 Inf];
            app.DOEPanel = uipanel(app.GridLayout4);
            app.DOEPanel.Title = 'DOE';
            app.GridLayout = uigridlayout(app.DOEPanel);
            app.simpleNumberLabel = uilabel(app.GridLayout);
            app.simpleNumberLabel.Text = '采样点数: ';
            app.simpleNumberH = uieditfield(app.GridLayout, 'numeric');
            app.simpleNumberH.Limits = [0 Inf];
            app.simpleNumberH.Value = 512;
            app.xmmLabel = uilabel(app.GridLayout);
            app.xmmLabel.Text = '高x宽(mm): ';
            app.simpleNumberW = uieditfield(app.GridLayout, 'numeric');
            app.simpleNumberW.Limits = [0 Inf];
            app.simpleNumberW.Value = 512;
            app.xLabel = uilabel(app.GridLayout);
            app.xLabel.Text = 'x';
            app.DOEH = uieditfield(app.GridLayout, 'numeric');
            app.DOEH.Limits = [0 Inf];
            app.DOEH.Value = 10;
            app.DOEW = uieditfield(app.GridLayout, 'numeric');
            app.DOEW.Limits = [0 Inf];
            app.DOEW.Value = 10;
            app.xLabel_2 = uilabel(app.GridLayout);
            app.xLabel_2.Text = 'x';
            app.n1EditField = uieditfield(app.GridLayout, 'text');
            app.n1EditField.Value = '1.34';
            app.n2EditField = uieditfield(app.GridLayout, 'text');
            app.n2EditField.Value = '1';
            app.xLabel_3 = uilabel(app.GridLayout);
            app.xLabel_3.Text = 'x';
            app.n1xn2Label = uilabel(app.GridLayout);
            app.n1xn2Label.Text = '折射率n1xn2: ';
            app.TargetPanel = uipanel(app.GridLayout4);
            app.TargetPanel.Title = '目标振幅分布';
            app.GridLayout3 = uigridlayout(app.TargetPanel);
            app.targetImgUIAxes = uiaxes(app.GridLayout3);
            app.targetImgUIAxes.XTick = [];
            app.targetImgUIAxes.XTickLabel = {};
            app.targetImgUIAxes.YTick = [];
            app.targetImgUIAxes.YTickLabel = {};
            app.targetImgUIAxes.ZTick = [0 0.5 1];
            app.targetImgUIAxes.ZTickLabel = '';
            app.loadTargetImgButton = uibutton(app.GridLayout3, 'push');
            app.loadTargetImgButton.ButtonPushedFcn = createCallbackFcn(app, @loadTargetImgButtonPushed, true);
            app.loadTargetImgButton.Icon = 'Image_open.png';
            app.loadTargetImgButton.Text = '加载目标图像';
            app.CheckBox = uicheckbox(app.GridLayout3);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '反转';
            app.algorithmDropDown = uidropdown(app.GridLayout4);
            app.algorithmDropDown.Items = {'GS迭代'};
            app.algorithmDropDown.Value = 'GS迭代';
            app.startButton = uibutton(app.GridLayout4, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Icon = 'excute.png';
            app.startButton.Text = '开始';
            app.ampPanel = uipanel(app.GridLayout4);
            app.ampPanel.Title = '输出振幅分布';
            app.GridLayout7 = uigridlayout(app.ampPanel);
            app.outputImgUIAxes = uiaxes(app.GridLayout7);
            app.outputImgUIAxes.XTick = [];
            app.outputImgUIAxes.YTick = [];
            app.indicatorPanel = uipanel(app.GridLayout7);
            app.indicatorPanel.Title = '输出振幅评价参数';
            app.GridLayout5 = uigridlayout(app.indicatorPanel);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '相似性: ';
            app.similarityEditField = uieditfield(app.GridLayout5, 'numeric');
            app.similarityEditField.Editable = 'off';
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = '光能利用率: ';
            app.utilRateEditField = uieditfield(app.GridLayout5, 'numeric');
            app.utilRateEditField.Editable = 'off';
            app.SSEEditFieldLabel = uilabel(app.GridLayout5);
            app.SSEEditFieldLabel.Text = 'SSE: ';
            app.SSEEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SSEEditField.Editable = 'off';
            app.phasePanel = uipanel(app.GridLayout4);
            app.phasePanel.Title = 'DOE相位分布';
            app.GridLayout10 = uigridlayout(app.phasePanel);
            app.phaseUIAxes = uiaxes(app.GridLayout10);
            app.phaseUIAxes.XTick = [];
            app.phaseUIAxes.YTick = [];
            app.phaseOperPanel = uipanel(app.GridLayout10);
            app.phaseOperPanel.Title = '相位操作';
            app.GridLayout9 = uigridlayout(app.phaseOperPanel);
            app.phaseQuantiDropDown = uidropdown(app.GridLayout9);
            app.phaseQuantiDropDown.Items = {'连续', '8阶', '16阶'};
            app.phaseQuantiDropDown.ItemsData = {'1', '2', '3'};
            app.phaseQuantiDropDown.ValueChangedFcn = createCallbackFcn(app, @phaseQuantiDropDownValueChanged, true);
            app.phaseQuantiDropDown.Value = '1';
            app.phaseOperButton = uibutton(app.GridLayout9, 'push');
            app.phaseOperButton.ButtonPushedFcn = createCallbackFcn(app, @phaseOperButtonPushed, true);
            app.phaseOperButton.Icon = 'Image_export.png';
            app.phaseOperButton.Enable = 'off';
            app.phaseOperButton.Text = '导出';
            app.compleCheckBox = uicheckbox(app.GridLayout9);
            app.compleCheckBox.Text = '  补码输出';
            app.outputFormDropDown = uidropdown(app.GridLayout9);
            app.outputFormDropDown.Items = {'相位(0~2*PI)', '波长', '实际位移(nm)'};
            app.outputFormDropDown.ItemsData = {'1', '2', '3', ''};
            app.outputFormDropDown.Value = '1';
            % Show the figure after all components are created
            app.DDOEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doeapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DDOEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DDOEUIFigure)
        end
    end
end